classdef afxRs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        afxRsUIFigure                  matlab.ui.Figure
        ProjectDropDownLabel           matlab.ui.control.Label
        ProjectDropDown                matlab.ui.control.DropDown
        NewProjectButton               matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        PreprocessingTab               matlab.ui.container.Tab
        ReslicestrucEditFieldLabel     matlab.ui.control.Label
        ReslicestrucEditField          matlab.ui.control.NumericEditField
        FWHMEditFieldLabel             matlab.ui.control.Label
        FWHMEditField                  matlab.ui.control.NumericEditField
        DummyscansLabel                matlab.ui.control.Label
        DummyscansEditField            matlab.ui.control.NumericEditField
        TREditFieldLabel               matlab.ui.control.Label
        TREditField                    matlab.ui.control.NumericEditField
        PreprocessingButton            matlab.ui.control.Button
        DirectoryEditFieldLabel        matlab.ui.control.Label
        DirectoryEditField             matlab.ui.control.EditField
        selectButton                   matlab.ui.control.Button
        ReslicefuncEditFieldLabel      matlab.ui.control.Label
        ReslicefuncEditField           matlab.ui.control.NumericEditField
        SliceorderEditFieldLabel       matlab.ui.control.Label
        SliceorderEditField            matlab.ui.control.EditField
        ListBox                        matlab.ui.control.ListBox
        ScanButton                     matlab.ui.control.Button
        FirstlevelTab                  matlab.ui.container.Tab
        RunFirstlevelButton            matlab.ui.control.Button
        WholebrainCheckBox             matlab.ui.control.CheckBox
        NetworkCheckBox                matlab.ui.control.CheckBox
        ROIsDropDownLabel              matlab.ui.control.Label
        ROIsDropDown                   matlab.ui.control.DropDown
        DenoisingoptionsDropDownLabel  matlab.ui.control.Label
        DenoisingoptionsDropDown       matlab.ui.control.DropDown
        LabelRoiInfo                   matlab.ui.control.Label
        ALFFCheckBox                   matlab.ui.control.CheckBox
        fALFFCheckBox                  matlab.ui.control.CheckBox
        AnalysesLabel                  matlab.ui.control.Label
        ViewButton                     matlab.ui.control.Button
        EditButton                     matlab.ui.control.Button
        NewButton                      matlab.ui.control.Button
        FWHMEditField_2Label           matlab.ui.control.Label
        FWHMEditField_2                matlab.ui.control.NumericEditField
        QualityCheckBox                matlab.ui.control.CheckBox
        PiniROICheckBox                matlab.ui.control.CheckBox
        SecondlevelTab                 matlab.ui.container.Tab
        FirstlevelDropDownLabel        matlab.ui.control.Label
        FirstlevelDropDown             matlab.ui.control.DropDown
        CovariatesListBoxLabel         matlab.ui.control.Label
        CovariatesListBox              matlab.ui.control.ListBox
        LabelFirstlevelinfo            matlab.ui.control.Label
        GroupbyListBoxLabel            matlab.ui.control.Label
        GroupbyListBox                 matlab.ui.control.ListBox
        RunButtonSecondlevel           matlab.ui.control.Button
        MeasureDropDownLabel           matlab.ui.control.Label
        MeasureDropDown                matlab.ui.control.DropDown
        SecondlevelDropDownLabel       matlab.ui.control.Label
        SecondlevelDropDown            matlab.ui.control.DropDown
        viewSPMButton                  matlab.ui.control.Button
        viewButton                     matlab.ui.control.Button
        ViewfirstlevelButton           matlab.ui.control.Button
        ConditionsListBoxLabel         matlab.ui.control.Label
        ConditionsListBox              matlab.ui.control.ListBox
        LabelProjectInfo               matlab.ui.control.Label
    end


    properties (Access = public)
        subjects % Description
        projectName
        denoisingOptions
        rois
        firstlevelInfo
        rawData
    end

    methods (Access = private)

        function disableGui(app)
            app.afxRsUIFigure.Visible = 'off';
        end

        function enableGui(app)
            app.afxRsUIFigure.Visible = 'on';
        end
        
        function loadDenoisingOptions(app)
            app.DenoisingoptionsDropDown.Items = {};
            app.DenoisingoptionsDropDown.ItemsData = {};
            d = dir(fullfile('denoisingOptions','*smoothed*.mat'));
            for i = 1:length(d)
                [~,name,~] = fileparts(d(i).name);
                app.DenoisingoptionsDropDown.Items{end+1} = name;
                app.DenoisingoptionsDropDown.ItemsData{end+1} = fullfile(d(i).folder,d(i).name);
            end
            app.loadDenoisingOption();
        end
        
        function loadDenoisingOption(app)
            value = app.DenoisingoptionsDropDown.Value;
            dat = load(value);
            app.denoisingOptions = dat.denoisingOptions;
        end
        
        function loadProjects(app)
            app.ProjectDropDown.Items = {};
            app.ProjectDropDown.ItemsData = {};
            d = dir(fullfile('data','*','subjects','subjects*.mat'));
            for i = 1:length(d)
                [pth,~,~] = fileparts(d(i).folder);
                [~,pth,~] = fileparts(pth);
                [~,name,~] = fileparts(d(i).name);
                app.ProjectDropDown.Items{end+1} = sprintf('%s (%s)',pth,name);
                app.ProjectDropDown.ItemsData{end+1} = fullfile(d(i).folder,d(i).name);
            end
            if i > 0
                app.loadProject();
            end
        end
        
        function loadProject(app)
            % load subjects
            app.loadSubjects();
            % load ROIs
            app.loadRois();
            % load firstlevels
            app.loadFirstlevels();
            % get preproc options
            app.loadPreprocOptions();
        end

        function loadSubjects(app)
            value = app.ProjectDropDown.Value;
            % load subjects
            dat = load(value);
            app.subjects = dat.subjects;
            clear dat;
            [tmp,~,~] = fileparts(value);
            [tmp,~,~] = fileparts(tmp);
            [~,tmp,~] = fileparts(tmp);
            app.projectName = tmp;
            nSub = length(app.subjects);
            if ~isempty(app.subjects)
                nExc = nnz([app.subjects.exclude]);
                nRest = length([app.subjects.conditions]);
            else
                nExc = 0;
                nRest = 0;
            end
            
            if isfield(app.subjects,'info') && isfield(app.subjects(1).info,'age')
                tmp = [app.subjects(~[app.subjects.exclude]).info];
                age = [tmp.age];
                meanAge = mean(age);
                stdAge = std(age);
            else
                meanAge = NaN;
                stdAge = NaN;
            end
            app.LabelProjectInfo.Text = sprintf('%d subjects (%d excluded) with a total of %d resting-state sessions.\nAge: %.1f +- %.1f',nSub,nExc,nRest,meanAge,stdAge);
        end
        
        function loadPreprocOptions(app)
            if ~isempty(app.subjects)
                defaults = app.subjects(end).preprocOptions;
                app.DummyscansEditField.Value = defaults.dummmyScans;
                app.TREditField.Value = defaults.TR;
                app.ReslicefuncEditField.Value = defaults.resliceFunc(1);
                app.ReslicestrucEditField.Value = defaults.resliceStruct(1);
                app.FWHMEditField.Value = defaults.fwhm(1);
                if isfield(defaults,'sliceorder')
                    app.SliceorderEditField.Value = mat2str(defaults.sliceorder);
                else
                    app.SliceorderEditField.Value = '[]';
                end
            else
                app.DummyscansEditField.Value = 0;
                app.TREditField.Value = Inf;
                app.ReslicefuncEditField.Value = 3;
                app.ReslicestrucEditField.Value = 1;
                app.FWHMEditField.Value = 5;
                app.SliceorderEditField.Value = '[]';
            end
        end

        function loadFirstlevels(app)
            app.FirstlevelDropDown.Items = {};
            app.FirstlevelDropDown.ItemsData = {};
            d = dir(fullfile('results',app.projectName,'*','*','firstlevel_info.mat'));
            for i=1:length(d)
                [pth,nameDenOpt,~] = fileparts(d(i).folder);
                [~,nameRois,~] = fileparts(pth);
                app.FirstlevelDropDown.Items{end+1} = sprintf('%s (%s)',nameRois,nameDenOpt);
                app.FirstlevelDropDown.ItemsData{end+1} = fullfile(d(i).folder,d(i).name);
            end
            app.loadFirstlevel();
        end
        
        function loadFirstlevel(app)
            value = app.FirstlevelDropDown.Value;
            app.MeasureDropDown.Items = {};
            app.MeasureDropDown.ItemsData = {};
            app.LabelFirstlevelinfo.Text = '';
            app.CovariatesListBox.Items = {};
            app.SecondlevelDropDown.Items = {};
            app.SecondlevelDropDown.ItemsData = {};
            app.ConditionsListBox.Items = {};
            
            if exist(value,'file')
                % get firstlevel info
                app.firstlevelInfo = value;
                dat = load(app.firstlevelInfo);
                nSub = nnz(~[dat.subjects.exclude]);
                nRoi = length(dat.rois);
                tmpCond = [dat.subjects(~[dat.subjects.exclude]).conditions];
                app.ConditionsListBox.Items = unique({tmpCond.name});
                app.ConditionsListBox.Value = app.ConditionsListBox.Items;
                if isfield(dat.subjects,'info')                
                    app.CovariatesListBox.Items = fieldnames(dat.subjects(1).info);
                else
                    app.CovariatesListBox.Items = {};
                end
                app.LabelFirstlevelinfo.Text = sprintf('contains %d subjects and %d rois',nSub,nRoi);
                % analyses
                for i = 1:length(dat.analyses)
                    switch dat.analyses{i}
                        case 'fc_wholebrain'
                            app.MeasureDropDown.Items{end+1} = 'FC (whole-brain)';
                            app.MeasureDropDown.ItemsData{end+1} = 'roi';
                            app.MeasureDropDown.Items{end+1} = 'LNSM';
                            app.MeasureDropDown.ItemsData{end+1} = 'lnsm';
                        case 'ALFF'
                            app.MeasureDropDown.Items{end+1} = 'ALFF';
                            app.MeasureDropDown.ItemsData{end+1} = 'ALFF';
                        case 'fALFF'
                            app.MeasureDropDown.Items{end+1} = 'fALFF';
                            app.MeasureDropDown.ItemsData{end+1} = 'fALFF';
                    end
                end
                % estimated secondlevels
                app.loadSecondlevels()
            end
            app.GroupbyListBox.Items = app.CovariatesListBox.Items;
            app.CovariatesListBox.Value = {};
            app.GroupbyListBox.Value = {};
        end
        
        function loadRois(app)
            app.ROIsDropDown.Items = {};
            app.ROIsDropDown.ItemsData = {};
            d = dir(fullfile('data',app.projectName,'rois','*.mat'));
            for i = 1:length(d)
                [~,name,~] = fileparts(d(i).name);
                app.ROIsDropDown.Items{end+1} = name(6:end);
                app.ROIsDropDown.ItemsData{end+1} = fullfile(d(i).folder,d(i).name);
            end
            app.loadRoi();
        end
        
        function loadRoi(app)
            value = app.ROIsDropDown.Value;
            if exist(value,'file')
                dat = load(value);
                app.rois = dat.rois;
                clear dat;
                nRois = length(app.rois);
                app.LabelRoiInfo.Text = sprintf('contains %d ROIs',nRois);
            else
                app.rois = [];
                app.LabelRoiInfo.Text = '';
            end
        end

        function loadSecondlevels(app)
                [pth,~,~] = fileparts(app.firstlevelInfo);
                d = dir(fullfile(pth,'secondlevel','flexibleFactorial','*','SPM.mat'));
                for i = 1:length(d)
                    [~,name,~] = fileparts(d(i).folder);
                    app.SecondlevelDropDown.Items{end+1} = name;
                    app.SecondlevelDropDown.ItemsData{end+1} = fullfile(d(i).folder,'SPM.mat');
                end
        end
        

        function runFirstlevel(app)
            analyses = {};
            if app.QualityCheckBox.Value, analyses{end+1} = 'quality'; end
            if app.WholebrainCheckBox.Value, analyses{end+1} = 'fc_wholebrain'; end
            if app.NetworkCheckBox.Value, analyses{end+1} = 'fc_network'; end
            if app.ALFFCheckBox.Value, analyses{end+1} = 'ALFF'; end
            if app.fALFFCheckBox.Value, analyses{end+1} = 'fALFF'; end
            if app.PiniROICheckBox.Value, analyses{end+1} = 'piniroi'; end
            sRois = app.ROIsDropDown.Items{strcmp(app.ROIsDropDown.ItemsData,app.ROIsDropDown.Value)};
            sDenOpt = app.DenoisingoptionsDropDown.Items{strcmp(app.DenoisingoptionsDropDown.ItemsData,app.DenoisingoptionsDropDown.Value)};
            if isfield(app.denoisingOptions,'sFWHM')
                sDenOpt = sprintf('%s-s%d',sDenOpt,app.denoisingOptions.sFWHM);
            end
            if app.ALFFCheckBox.Value || app.fALFFCheckBox.Value
                curRois = [];
                analysisName = fullfile('amplitudes',sDenOpt);
            else
                curRois = app.rois;
                analysisName = fullfile(sRois,sDenOpt);
            end
            addpath('scripts');
            app.disableGui();
            afxFirstlevel(app.subjects,app.denoisingOptions,curRois,analysisName,analyses);
            app.enableGui();
            rmpath('scripts');
            app.loadFirstlevels();
        end
        
        function runSecondlevel(app)
            tmp = pwd;
            addpath('scripts');
            app.disableGui();
            if strcmp(app.MeasureDropDown.Value,'lnsm')
                afxLnmSecondlevel(app.firstlevelInfo,[],true);
            else
                afxSecondlevel(app.firstlevelInfo,app.CovariatesListBox.Value,app.GroupbyListBox.Value,app.ConditionsListBox.Value,app.MeasureDropDown.Value);
            end
            cd(tmp);
            app.loadSecondlevels();
            app.enableGui();
            rmpath('scripts');
        end
        
        function runPreprocessing(app)
            tmp = pwd;
            addpath('scripts');
            app.disableGui();
            options.resliceStruct = repmat(app.ReslicestrucEditField.Value,1,3);
            options.resliceFunc = repmat(app.ReslicefuncEditField.Value,1,3);
            options.fwhm = repmat(app.FWHMEditField.Value,1,3);
            options.dummmyScans = app.DummyscansEditField.Value;
            options.TR = app.TREditField.Value;
            options.sliceorder = eval(app.SliceorderEditField.Value);
            for i = 1:length(app.ListBox.Value)
                cur = app.rawData(app.ListBox.Value{i});
                afxPreproc(cur.func,cur.struc,options,cur.cond,app.projectName,cur.subjectName,cur.info);
            end
            cd(tmp);
            app.loadSubjects();
            app.enableGui();
            rmpath('scripts');    
        end
        
        function viewRois(app)
            addpath('scripts');
            afxOrthviews('reset');
            atlasCache = struct('file',[],'dat',[]);
            for i = 1:length(app.rois)
                switch app.rois(i).type
                    case 'sphere'
                        afxOrthviews('sphere',app.rois(i).coords,app.rois(i).radius);
                    case 'image'
                        afxOrthviews('mask',app.rois(i).file);
                    case 'atlas'
                        if ~strcmp(atlasCache.file,app.rois(i).file)
                            atlasCache.Vi = spm_vol(app.rois(i).file);
                            atlasCache.dat = spm_read_vols(atlasCache.Vi);
                            atlasCache.file = app.rois(i).file;
                        end
                        ind = find(atlasCache.dat > app.rois(i).pick-.5 & atlasCache.dat < app.rois(i).pick+.5);
                        [X,Y,Z] = ind2sub(atlasCache.Vi.dim,ind);
                        XYZ = [X Y Z]';
                        clear X Y dat ind;
                        mXYZ = atlasCache.Vi.mat*[mean(XYZ,2);1];
                        mXYZ = mXYZ(1:3);
                        spm_orthviews('addcolouredblobs',1,XYZ,1,atlasCache.Vi.mat,[.8 0 0]);
                        spm_orthviews('reposition',mXYZ);
                end
            end
            [~,name,~] = fileparts(app.ROIsDropDown.Value);
            afxOrthviews('title',['ROIs: ' name]);
            rmpath('scripts');
        end
        
        function loadRawData(app)
            xlsFname = fullfile(app.DirectoryEditField.Value,'info.xlsx');
            if exist(xlsFname,'file')
                [~,~,raw] = xlsread(xlsFname);
                xlsInfo.fields = raw(1,2:end);
                xlsInfo.subjects = raw(2:end,1);
                xlsInfo.data = raw(2:end,2:end);
            end
            
            d = dir(fullfile(app.DirectoryEditField.Value,'*'));
            d = d([d.isdir]);
            d(1:2) = [];
            app.rawData = struct([]);
            app.ListBox.Items = {};
            app.ListBox.ItemsData = {};
            app.ListBox.Value = {};
            for iSub = 1:length(d)
                rawDir = fullfile(d(iSub).folder,d(iSub).name);
                d2 = dir(fullfile(rawDir,'func','*'));
                if ~isempty(d2), d2(1:2) = []; end
                tmp = 0;
                app.rawData(iSub).cond = {};
                app.rawData(iSub).func = {};
                for iCond = 1:length(d2)
                    app.rawData(iSub).cond{iCond} = d2(iCond).name;
                    app.rawData(iSub).func{iCond} = cellstr(spm_select('ExtFPList',fullfile(rawDir,'func',d2(iCond).name),'.nii',Inf));
                    tmp(iCond) = size(app.rawData(iSub).func{iCond},1);
                end
                app.rawData(iSub).struc = spm_select('FPList',fullfile(rawDir,'struc'),'.nii');
                app.rawData(iSub).subjectName = d(iSub).name;
                app.rawData(iSub).info = struct([]);
                if exist('xlsInfo','var')
                    ind = strcmp(app.rawData(iSub).subjectName,xlsInfo.subjects);
                    if any(ind)
                        for iInfo = 1:length(xlsInfo.fields)
                            app.rawData(iSub).info(1).(xlsInfo.fields{iInfo}) = xlsInfo.data{ind,iInfo};
                        end
                    end
                end
                
                % check
                if size(app.rawData(iSub).struc,1) == 1 && length(app.rawData(iSub).cond) >= 1 && sum(diff(tmp(:))) == 0
                    app.rawData(iSub).valid = true;
                    hint = '   ';
                else
                    app.rawData(iSub).valid = false;
                    hint = '!! ';
                end
                
                % listbox
                app.ListBox.Items{iSub} = sprintf('%s%s: %d struct, %d conditions (%s niftis), %d variables',hint,app.rawData(iSub).subjectName,size(app.rawData(iSub).struc,1),length(app.rawData(iSub).cond),mat2str(tmp(:)),length(fieldnames(app.rawData(iSub).info)));
                app.ListBox.ItemsData{iSub} = iSub;
                if app.rawData(iSub).valid
                    app.ListBox.Value{end+1} = iSub;
                end
               
            end
            
        end
        
        function conditionalElements(app)
            % preproc button
            if ~isempty(app.ProjectDropDown.Value) && ~isempty(app.ListBox.Value)
                
                app.PreprocessingButton.Enable = 'on';
            else
                app.PreprocessingButton.Enable = 'off';
            end
              
            % scan button
            if isempty(app.DirectoryEditField.Value)
                app.ScanButton.Enable = 'off';
            else
                app.ScanButton.Enable = 'on';
            end
            
            % new, edit and view roi
            if isempty(app.ROIsDropDown.Value)
               app.ROIsDropDown.Enable = 'off';
               app.EditButton.Enable = 'off';
               app.ViewButton.Enable = 'off';
            else
               app.ROIsDropDown.Enable = 'on';
               app.EditButton.Enable = 'on';
               app.ViewButton.Enable = 'on';
            end
            
            % fwhm
            if isfield(app.denoisingOptions,'sFWHM')
                app.FWHMEditField_2.Enable = 'on';
                app.FWHMEditField_2.Value = app.denoisingOptions.sFWHM;
            else
                app.FWHMEditField_2.Enable = 'off';
                app.FWHMEditField_2.Value = 0;
            end
            
            % run firstlevel
            if ~isempty(app.ProjectDropDown.Value) && ~isempty(app.subjects)
                if (app.QualityCheckBox.Value || app.ALFFCheckBox.Value || app.fALFFCheckBox.Value)  || (~isempty(app.ROIsDropDown.Value) && (app.NetworkCheckBox.Value || app.WholebrainCheckBox.Value || app.PiniROICheckBox.Value))
                    app.RunFirstlevelButton.Enable = 'on';
                else
                    app.RunFirstlevelButton.Enable = 'off';
                end
            else
                app.RunFirstlevelButton.Enable = 'off';
            end
            
            % secondlevel measure, covariates, group factor
            if isempty(app.FirstlevelDropDown.Value)
                app.FirstlevelDropDown.Enable = 'off';
                app.MeasureDropDown.Enable = 'off';
                app.CovariatesListBox.Enable = 'off';
                app.GroupbyListBox.Enable = 'off';
            else
                app.FirstlevelDropDown.Enable = 'on';
                app.MeasureDropDown.Enable = 'on';
                app.CovariatesListBox.Enable = 'on';
                app.GroupbyListBox.Enable = 'on';
            end
            
            % secondlevel estimate
            if ~isempty(app.FirstlevelDropDown.Value) && ~isempty(app.MeasureDropDown.Value)
                app.RunButtonSecondlevel.Enable = 'on';
            else
                app.RunButtonSecondlevel.Enable = 'off';
            end
            
            % secondlevel view results
            if isempty(app.SecondlevelDropDown.Value)
                app.viewSPMButton.Enable = 'off';
                app.viewButton.Enable = 'off';
                app.SecondlevelDropDown.Enable = 'off';                
            else
                app.viewSPMButton.Enable = 'on';
                app.viewButton.Enable = 'on';
                app.SecondlevelDropDown.Enable = 'on';
            end
            
            % other
            if isempty(app.ProjectDropDown.Value)
                app.NewButton.Enable = 'off';
                app.NetworkCheckBox.Enable = 'off';
                app.WholebrainCheckBox.Enable = 'off';
                app.ALFFCheckBox.Enable = 'off';
                app.fALFFCheckBox.Enable = 'off';
                app.DummyscansEditField.Enable = 'off';
                app.TREditField.Enable = 'off';
                app.ReslicefuncEditField.Enable = 'off';
                app.ReslicestrucEditField.Enable = 'off';
                app.FWHMEditField.Enable = 'off';
                app.SliceorderEditField.Enable = 'off';
                app.ListBox.Enable = 'off';
                app.DirectoryEditField.Enable = 'off';
                app.selectButton.Enable = 'off';
            else
                app.NewButton.Enable = 'on';
                app.NetworkCheckBox.Enable = 'on';
                app.WholebrainCheckBox.Enable = 'on';
                app.ALFFCheckBox.Enable = 'on';
                app.fALFFCheckBox.Enable = 'on';
                app.DummyscansEditField.Enable = 'on';
                app.TREditField.Enable = 'on';
                app.ReslicefuncEditField.Enable = 'on';
                app.ReslicestrucEditField.Enable = 'on';
                app.FWHMEditField.Enable = 'on';
                app.SliceorderEditField.Enable = 'on';
                app.ListBox.Enable = 'on';
                app.DirectoryEditField.Enable = 'on';
                app.selectButton.Enable = 'on';
            end
                
        end
        
        function [dim,mat,RCP,XYZmm] = initViewer(app)
            spm_figure('GetWin','Graphics'); spm_figure('Clear','Graphics'); spm_orthviews('Reset');
            template = spm_vol(fullfile('masks','ch2better.nii'));
            dim = template.dim;
            mat = template.mat;
            spm_orthviews('Image', template, [0.0 0.22 1 .8]);
            [R,C,P]  = ndgrid(1:dim(1),1:dim(2),1:dim(3));
            RCP      = [R(:)';C(:)';P(:)'];
            clear R C P
            RCP(4,:) = 1;
            XYZmm    = mat(1:3,:)*RCP;
        end
        
        function viewFirstlevel(app)
            measure = app.MeasureDropDown.Value;
            if strcmp(measure,'roi')
                info = load(app.FirstlevelDropDown.Value);
                d = fileparts(app.FirstlevelDropDown.Value);
                fname = {};
                titles = {};
                for iRoi = 1:length(info.rois)
                    for iSub = find(~[info.subjects.exclude])
                        for iCond = 1:length(info.subjects(iSub).conditions)
                            fname{end+1} = fullfile(d,'firstlevel',strcat('cond_',info.subjects(iSub).conditions(iCond).name),strcat('roi_',info.rois(iRoi).name),strcat(info.subjects(iSub).name,'.nii'));
                            titles{end+1} = sprintf('roi_%s | sub_%s | cond_%s',info.rois(iRoi).name, info.subjects(iSub).name, info.subjects(iSub).conditions(iCond).name);
                        end
                    end
                end
                c = afxViewer;
                c.selectImages(fname, 1, titles);
            elseif strcmp(measure,'ALFF') || strcmp(measure,'fALFF')
                info = load(app.FirstlevelDropDown.Value);
                d = fileparts(app.FirstlevelDropDown.Value);
                fname = {};
                titles = {};
                for iSub = find(~[info.subjects.exclude])
                    for iCond = 1:length(info.subjects(iSub).conditions)
                        fname{end+1} = fullfile(d,'firstlevel',strcat('cond_',info.subjects(iSub).conditions(iCond).name),measure,strcat(info.subjects(iSub).name,'.nii'));
                        titles{end+1} = sprintf('%s | sub_%s | cond_%s',measure, info.subjects(iSub).name, info.subjects(iSub).conditions(iCond).name);
                    end
                end
                c = afxViewer;
                c.selectImages(fname, 1, titles);
            end
            
        end

        function viewSecondlevel(app)
            c = afxViewer;
            c.selectImages(app.SecondlevelDropDown.Value, 2);
        end
        
    end

    methods (Access = public)
        
        function changedRois(app)
            app.loadRois();
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % load denoising options
            app.loadDenoisingOptions();
            % load projects
            app.loadProjects();
            app.conditionalElements();
        end

        % Value changed function: ALFFCheckBox
        function ALFFCheckBoxValueChanged(app, event)
            value = app.ALFFCheckBox.Value;
            if value
                app.WholebrainCheckBox.Value = false;
                app.NetworkCheckBox.Value = false;
            end
            app.conditionalElements();
        end

        % Value changed function: DenoisingoptionsDropDown
        function DenoisingoptionsDropDownValueChanged(app, event)
            app.loadDenoisingOption();
            app.conditionalElements();
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            h = afxRsRoi();
            h.init(app,app.ROIsDropDown.Value);
        end

        % Value changed function: FWHMEditField_2
        function FWHMEditField_2ValueChanged(app, event)
            value = app.FWHMEditField_2.Value;
            app.denoisingOptions.sFWHM = value;
        end

        % Value changed function: FirstlevelDropDown
        function FirstlevelDropDownValueChanged(app, event)
            app.loadFirstlevel();
            app.conditionalElements();
        end

        % Value changed function: NetworkCheckBox
        function NetworkCheckBoxValueChanged(app, event)
            value = app.NetworkCheckBox.Value;
            if value
                app.ALFFCheckBox.Value = false;
                app.fALFFCheckBox.Value = false;
            end
            app.conditionalElements();
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            name = inputdlg('Name for new ROI set?');
            if ~isempty(name)
                if isempty(regexp(name{1},'^[0-9abcdefghijklmnopqrstuvwxyzABCDEFGHIJKLMNOPQRSTUVWXYZ_]*$','once'))
                    warndlg('Only alphanumeric characters and underscores are allowed ...');
                else
                    roiFname = fullfile('data',app.projectName,'rois',strcat('rois_',name{1},'.mat'));
                    if exist(roiFname,'file')
                        warndlg('File already exists ...');
                    else
                        rois = struct([]);
                        save(roiFname,'rois');
                        app.loadRois();
                        app.ROIsDropDown.Value = fullfile(pwd,roiFname);
                        app.loadRoi();
                    end
                end
            end
            app.conditionalElements();
        end

        % Button pushed function: NewProjectButton
        function NewProjectButtonPushed(app, event)
            name = inputdlg('Name for new project?');
            if ~isempty(name)
                if isempty(regexp(name{1},'^[0-9abcdefghijklmnopqrstuvwxyzABCDEFGHIJKLMNOPQRSTUVWXYZ_]*$','once'))
                    warndlg('Only alphanumeric characters and underscores are allowed ...');
                else
                    prjDir = fullfile('data',name{1});
                    if exist(prjDir,'dir')
                        warndlg('Project directory already exists ...');
                    else
                        app.projectName = name{1};
                        mkdir(fullfile(prjDir));
                        mkdir(fullfile(prjDir,'subjects'));
                        mkdir(fullfile(prjDir,'rois'));
                        subjects = struct([]);
                        save(fullfile(prjDir,'subjects','subjects.mat'),'subjects');
                        app.loadProjects();
                        app.ProjectDropDown.Value = fullfile(pwd,prjDir,'subjects','subjects.mat');
                        app.loadProject();
                    end
                end
            end
            app.conditionalElements();
        end

        % Button pushed function: PreprocessingButton
        function PreprocessingButtonPushed(app, event)
            app.runPreprocessing();
            app.conditionalElements();
        end

        % Value changed function: ProjectDropDown
        function ProjectDropDownValueChanged(app, event)
            app.loadProject();
            app.conditionalElements();
        end

        % Value changed function: QualityCheckBox
        function QualityCheckBoxValueChanged(app, event)
            value = app.QualityCheckBox.Value;
            app.conditionalElements();
        end

        % Value changed function: ROIsDropDown
        function ROIsDropDownValueChanged(app, event)
            app.loadRoi();
            app.conditionalElements();
        end

        % Button pushed function: RunButtonSecondlevel
        function RunButtonSecondlevelPushed(app, event)
            app.runSecondlevel();
            app.conditionalElements();
        end

        % Button pushed function: RunFirstlevelButton
        function RunFirstlevelButtonPushed(app, event)
            app.runFirstlevel();
            app.conditionalElements();
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            app.loadRawData();
            app.conditionalElements();
        end

        % Value changed function: SecondlevelDropDown
        function SecondlevelDropDownValueChanged(app, event)
            
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            app.viewRois();
        end

        % Button pushed function: ViewfirstlevelButton
        function ViewfirstlevelButtonPushed(app, event)
            app.viewFirstlevel();
        end

        % Value changed function: WholebrainCheckBox
        function WholebrainCheckBoxValueChanged(app, event)
            value = app.WholebrainCheckBox.Value;
            if value
                app.ALFFCheckBox.Value = false;
                app.fALFFCheckBox.Value = false;
            end
            app.conditionalElements();
        end

        % Value changed function: fALFFCheckBox
        function fALFFCheckBoxValueChanged(app, event)
            value = app.fALFFCheckBox.Value;
            if value
                app.WholebrainCheckBox.Value = false;
                app.NetworkCheckBox.Value = false;
            end
            app.conditionalElements();
        end

        % Button pushed function: selectButton
        function selectButtonPushed(app, event)
            tmp = uigetdir([],'select ...');
            if ischar(tmp)
                app.DirectoryEditField.Value = tmp;
                app.conditionalElements();
            end
        end

        % Button pushed function: viewButton
        function viewButtonPushed(app, event)
            app.viewSecondlevel();
        end

        % Button pushed function: viewSPMButton
        function viewSPMButtonPushed(app, event)
            [pth,~,~] = fileparts(app.SecondlevelDropDown.Value);
            app.delete();
            cd(pth);
            spm_results_ui();
        end

        % Value changed function: PiniROICheckBox
        function PiniROICheckBoxValueChanged(app, event)
            value = app.PiniROICheckBox.Value;
            app.conditionalElements();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.afxRsUIFigure = uifigure;
            app.afxRsUIFigure.Name = 'afxRs';
            app.ProjectDropDownLabel = uilabel(app.afxRsUIFigure);
            app.ProjectDropDownLabel.Text = 'Project';
            app.ProjectDropDown = uidropdown(app.afxRsUIFigure);
            app.ProjectDropDown.Items = {};
            app.ProjectDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectDropDownValueChanged, true);
            app.ProjectDropDown.Value = {};
            app.NewProjectButton = uibutton(app.afxRsUIFigure, 'push');
            app.NewProjectButton.ButtonPushedFcn = createCallbackFcn(app, @NewProjectButtonPushed, true);
            app.NewProjectButton.Text = 'New Project';
            app.TabGroup = uitabgroup(app.afxRsUIFigure);
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Preprocessing';
            app.ReslicestrucEditFieldLabel = uilabel(app.PreprocessingTab);
            app.ReslicestrucEditFieldLabel.Text = 'Reslice struc';
            app.ReslicestrucEditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.ReslicestrucEditField.Limits = [1 3];
            app.ReslicestrucEditField.Value = 1;
            app.FWHMEditFieldLabel = uilabel(app.PreprocessingTab);
            app.FWHMEditFieldLabel.Text = 'FWHM';
            app.FWHMEditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.FWHMEditField.Limits = [0 15];
            app.FWHMEditField.Value = 5;
            app.DummyscansLabel = uilabel(app.PreprocessingTab);
            app.DummyscansLabel.Text = 'Dummy scans';
            app.DummyscansEditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.DummyscansEditField.Limits = [0 15];
            app.TREditFieldLabel = uilabel(app.PreprocessingTab);
            app.TREditFieldLabel.Text = 'TR';
            app.TREditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.TREditField.Limits = [0 Inf];
            app.TREditField.Value = Inf;
            app.PreprocessingButton = uibutton(app.PreprocessingTab, 'push');
            app.PreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessingButtonPushed, true);
            app.PreprocessingButton.Text = 'Preprocessing';
            app.DirectoryEditFieldLabel = uilabel(app.PreprocessingTab);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.PreprocessingTab, 'text');
            app.DirectoryEditField.Editable = 'off';
            app.selectButton = uibutton(app.PreprocessingTab, 'push');
            app.selectButton.ButtonPushedFcn = createCallbackFcn(app, @selectButtonPushed, true);
            app.selectButton.Text = 'select';
            app.ReslicefuncEditFieldLabel = uilabel(app.PreprocessingTab);
            app.ReslicefuncEditFieldLabel.Text = 'Reslice func';
            app.ReslicefuncEditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.ReslicefuncEditField.Limits = [0 10];
            app.ReslicefuncEditField.Value = 3;
            app.SliceorderEditFieldLabel = uilabel(app.PreprocessingTab);
            app.SliceorderEditFieldLabel.Text = 'Sliceorder';
            app.SliceorderEditField = uieditfield(app.PreprocessingTab, 'text');
            app.SliceorderEditField.Value = '[]';
            app.ListBox = uilistbox(app.PreprocessingTab);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {};
            app.ScanButton = uibutton(app.PreprocessingTab, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.FirstlevelTab = uitab(app.TabGroup);
            app.FirstlevelTab.Title = 'Firstlevel';
            app.RunFirstlevelButton = uibutton(app.FirstlevelTab, 'push');
            app.RunFirstlevelButton.ButtonPushedFcn = createCallbackFcn(app, @RunFirstlevelButtonPushed, true);
            app.RunFirstlevelButton.Text = 'Estimate';
            app.WholebrainCheckBox = uicheckbox(app.FirstlevelTab);
            app.WholebrainCheckBox.ValueChangedFcn = createCallbackFcn(app, @WholebrainCheckBoxValueChanged, true);
            app.WholebrainCheckBox.Text = 'Whole-brain';
            app.WholebrainCheckBox.Value = true;
            app.NetworkCheckBox = uicheckbox(app.FirstlevelTab);
            app.NetworkCheckBox.ValueChangedFcn = createCallbackFcn(app, @NetworkCheckBoxValueChanged, true);
            app.NetworkCheckBox.Text = 'Network';
            app.NetworkCheckBox.Value = true;
            app.ROIsDropDownLabel = uilabel(app.FirstlevelTab);
            app.ROIsDropDownLabel.Text = 'ROIs';
            app.ROIsDropDown = uidropdown(app.FirstlevelTab);
            app.ROIsDropDown.Items = {};
            app.ROIsDropDown.ValueChangedFcn = createCallbackFcn(app, @ROIsDropDownValueChanged, true);
            app.ROIsDropDown.Value = {};
            app.DenoisingoptionsDropDownLabel = uilabel(app.FirstlevelTab);
            app.DenoisingoptionsDropDownLabel.Text = 'Denoising options';
            app.DenoisingoptionsDropDown = uidropdown(app.FirstlevelTab);
            app.DenoisingoptionsDropDown.Items = {};
            app.DenoisingoptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @DenoisingoptionsDropDownValueChanged, true);
            app.DenoisingoptionsDropDown.Value = {};
            app.LabelRoiInfo = uilabel(app.FirstlevelTab);
            app.LabelRoiInfo.Text = '';
            app.ALFFCheckBox = uicheckbox(app.FirstlevelTab);
            app.ALFFCheckBox.ValueChangedFcn = createCallbackFcn(app, @ALFFCheckBoxValueChanged, true);
            app.ALFFCheckBox.Text = 'ALFF';
            app.fALFFCheckBox = uicheckbox(app.FirstlevelTab);
            app.fALFFCheckBox.ValueChangedFcn = createCallbackFcn(app, @fALFFCheckBoxValueChanged, true);
            app.fALFFCheckBox.Text = 'fALFF';
            app.AnalysesLabel = uilabel(app.FirstlevelTab);
            app.AnalysesLabel.Text = 'Analyses';
            app.ViewButton = uibutton(app.FirstlevelTab, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.EditButton = uibutton(app.FirstlevelTab, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.NewButton = uibutton(app.FirstlevelTab, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.FWHMEditField_2Label = uilabel(app.FirstlevelTab);
            app.FWHMEditField_2Label.Text = 'FWHM';
            app.FWHMEditField_2 = uieditfield(app.FirstlevelTab, 'numeric');
            app.FWHMEditField_2.Limits = [0 15];
            app.FWHMEditField_2.ValueChangedFcn = createCallbackFcn(app, @FWHMEditField_2ValueChanged, true);
            app.QualityCheckBox = uicheckbox(app.FirstlevelTab);
            app.QualityCheckBox.ValueChangedFcn = createCallbackFcn(app, @QualityCheckBoxValueChanged, true);
            app.QualityCheckBox.Text = 'Quality';
            app.PiniROICheckBox = uicheckbox(app.FirstlevelTab);
            app.PiniROICheckBox.ValueChangedFcn = createCallbackFcn(app, @PiniROICheckBoxValueChanged, true);
            app.PiniROICheckBox.Text = 'Pini ROI';
            app.SecondlevelTab = uitab(app.TabGroup);
            app.SecondlevelTab.Title = 'Secondlevel';
            app.FirstlevelDropDownLabel = uilabel(app.SecondlevelTab);
            app.FirstlevelDropDownLabel.Text = 'Firstlevel';
            app.FirstlevelDropDown = uidropdown(app.SecondlevelTab);
            app.FirstlevelDropDown.Items = {};
            app.FirstlevelDropDown.ValueChangedFcn = createCallbackFcn(app, @FirstlevelDropDownValueChanged, true);
            app.FirstlevelDropDown.Value = {};
            app.CovariatesListBoxLabel = uilabel(app.SecondlevelTab);
            app.CovariatesListBoxLabel.Text = 'Covariates';
            app.CovariatesListBox = uilistbox(app.SecondlevelTab);
            app.CovariatesListBox.Items = {};
            app.CovariatesListBox.Multiselect = 'on';
            app.CovariatesListBox.Value = {};
            app.LabelFirstlevelinfo = uilabel(app.SecondlevelTab);
            app.LabelFirstlevelinfo.Text = '';
            app.GroupbyListBoxLabel = uilabel(app.SecondlevelTab);
            app.GroupbyListBoxLabel.Text = 'Group by';
            app.GroupbyListBox = uilistbox(app.SecondlevelTab);
            app.GroupbyListBox.Items = {};
            app.GroupbyListBox.Value = {};
            app.RunButtonSecondlevel = uibutton(app.SecondlevelTab, 'push');
            app.RunButtonSecondlevel.ButtonPushedFcn = createCallbackFcn(app, @RunButtonSecondlevelPushed, true);
            app.RunButtonSecondlevel.Text = 'Estimate';
            app.MeasureDropDownLabel = uilabel(app.SecondlevelTab);
            app.MeasureDropDownLabel.Text = 'Measure';
            app.MeasureDropDown = uidropdown(app.SecondlevelTab);
            app.MeasureDropDown.Items = {};
            app.MeasureDropDown.Value = {};
            app.SecondlevelDropDownLabel = uilabel(app.SecondlevelTab);
            app.SecondlevelDropDownLabel.Text = 'Secondlevel';
            app.SecondlevelDropDown = uidropdown(app.SecondlevelTab);
            app.SecondlevelDropDown.Items = {};
            app.SecondlevelDropDown.ValueChangedFcn = createCallbackFcn(app, @SecondlevelDropDownValueChanged, true);
            app.SecondlevelDropDown.Value = {};
            app.viewSPMButton = uibutton(app.SecondlevelTab, 'push');
            app.viewSPMButton.ButtonPushedFcn = createCallbackFcn(app, @viewSPMButtonPushed, true);
            app.viewSPMButton.Text = 'view (SPM)';
            app.viewButton = uibutton(app.SecondlevelTab, 'push');
            app.viewButton.ButtonPushedFcn = createCallbackFcn(app, @viewButtonPushed, true);
            app.viewButton.Text = 'view';
            app.ViewfirstlevelButton = uibutton(app.SecondlevelTab, 'push');
            app.ViewfirstlevelButton.ButtonPushedFcn = createCallbackFcn(app, @ViewfirstlevelButtonPushed, true);
            app.ViewfirstlevelButton.Text = 'View firstlevel';
            app.ConditionsListBoxLabel = uilabel(app.SecondlevelTab);
            app.ConditionsListBoxLabel.Text = 'Conditions';
            app.ConditionsListBox = uilistbox(app.SecondlevelTab);
            app.ConditionsListBox.Items = {};
            app.ConditionsListBox.Multiselect = 'on';
            app.ConditionsListBox.Value = {};
            app.LabelProjectInfo = uilabel(app.afxRsUIFigure);
            app.LabelProjectInfo.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = afxRs

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.afxRsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.afxRsUIFigure)
        end
    end
end