classdef afxRsRoi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        afxRsRoiUIFigure        matlab.ui.Figure
        UITable                 matlab.ui.control.Table
        SaveButton              matlab.ui.control.Button
        FilenameEditFieldLabel  matlab.ui.control.Label
        FilenameEditField       matlab.ui.control.EditField
        deleterowsButton        matlab.ui.control.Button
        insertfilenameButton    matlab.ui.control.Button
        insertrowButton         matlab.ui.control.Button
    end


    properties (Access = private)
        parent % Description
        selection % Description
    end

    methods (Access = public)
    
        function init(app,parent,fname)
            app.parent = parent;
            app.FilenameEditField.Value = fname;
            header = {'name' 'type' 'file' 'pick' 'coords' 'radius' 'gmMask'};
            app.UITable.ColumnName = header;
            dat = load(fname);
            for i = 1:length(dat.rois)
                curRoi = dat.rois(i);
                for j = 1:length(header)
                    if isfield(curRoi,header{j})
                        if strcmp(header{j},'coords')
                            app.UITable.Data{i,j} = mat2str(curRoi.(header{j}));
                        else
                            app.UITable.Data{i,j} = curRoi.(header{j});
                        end
                    else
                        app.UITable.Data{i,j} = [];
                    end
                end
            end
            app.UITable.ColumnEditable = true;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            
        end

        % Button pushed function: deleterowsButton
        function deleterowsButtonPushed(app, event)
            for i = size(app.selection,1):-1:1
                app.UITable.Data(app.selection(i,1),:) = [];
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.selection = event.Indices;
        end

        % Button pushed function: insertrowButton
        function insertrowButtonPushed(app, event)
            app.UITable.Data{end+1,7} = [];
        end

        % Button pushed function: insertfilenameButton
        function insertfilenameButtonPushed(app, event)
            [FileName,PathName] = uigetfile('*.nii','Select *.nii file');
            app.UITable.Data{app.selection(1,1),3} = fullfile(PathName,FileName);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            header = {'name' 'type' 'file' 'pick' 'coords' 'radius' 'gmMask'};
            for i = 1:size(app.UITable.Data,1)
                for j = 1:length(header)
                    if isempty(app.UITable.Data{i,j})
                        rois(i).(header{j}) = [];
                    elseif (j == 4 || j == 6 || j == 7) && ischar(app.UITable.Data{i,j})
                        rois(i).(header{j}) = str2double(app.UITable.Data{i,j});
                    elseif j == 5 && ischar(app.UITable.Data{i,j})
                        rois(i).(header{j}) = eval(app.UITable.Data{i,j});
                    else
                        rois(i).(header{j}) = app.UITable.Data{i,j};
                    end
                end
            end
            save(app.FilenameEditField.Value,'rois');
            app.parent.changedRois();
            app.delete();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.afxRsRoiUIFigure = uifigure;
            app.afxRsRoiUIFigure.Name = 'afxRsRoi';
            app.UITable = uitable(app.afxRsRoiUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SaveButton = uibutton(app.afxRsRoiUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FilenameEditFieldLabel = uilabel(app.afxRsRoiUIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.afxRsRoiUIFigure, 'text');
            app.deleterowsButton = uibutton(app.afxRsRoiUIFigure, 'push');
            app.deleterowsButton.ButtonPushedFcn = createCallbackFcn(app, @deleterowsButtonPushed, true);
            app.deleterowsButton.Text = 'delete row(s)';
            app.insertfilenameButton = uibutton(app.afxRsRoiUIFigure, 'push');
            app.insertfilenameButton.ButtonPushedFcn = createCallbackFcn(app, @insertfilenameButtonPushed, true);
            app.insertfilenameButton.Text = 'insert filename';
            app.insertrowButton = uibutton(app.afxRsRoiUIFigure, 'push');
            app.insertrowButton.ButtonPushedFcn = createCallbackFcn(app, @insertrowButtonPushed, true);
            app.insertrowButton.Text = 'insert row';
        end
    end

    methods (Access = public)

        % Construct app
        function app = afxRsRoi

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.afxRsRoiUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.afxRsRoiUIFigure)
        end
    end
end