classdef afxViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        afxViewerUIFigure             matlab.ui.Figure
        imagesDropDownLabel           matlab.ui.control.Label
        imagesDropDown                matlab.ui.control.DropDown
        hightthresholdEditFieldLabel  matlab.ui.control.Label
        hightthresholdEditField       matlab.ui.control.NumericEditField
        clusterextentEditFieldLabel   matlab.ui.control.Label
        clusterextentEditField        matlab.ui.control.NumericEditField
        structuralunderlayLabel       matlab.ui.control.Label
        structuralunderlayEditField   matlab.ui.control.EditField
        SelectUnderlayButton          matlab.ui.control.Button
        XSpinnerLabel                 matlab.ui.control.Label
        XSpinner                      matlab.ui.control.Spinner
        YSpinnerLabel                 matlab.ui.control.Label
        YSpinner                      matlab.ui.control.Spinner
        ZSpinnerLabel                 matlab.ui.control.Label
        ZSpinner                      matlab.ui.control.Spinner
        positionMNImmLabel            matlab.ui.control.Label
        SelectImagesButton            matlab.ui.control.Button
        updateButton                  matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        crosshairCheckBox             matlab.ui.control.CheckBox
        ButtonGroup                   matlab.ui.container.ButtonGroup
        originalButton                matlab.ui.control.RadioButton
        maskButton                    matlab.ui.control.RadioButton
        x1x1mmButton                  matlab.ui.control.RadioButton
        x5x5mmButton                  matlab.ui.control.RadioButton
        saveasniftiButton             matlab.ui.control.Button
        saveasbitmapButton            matlab.ui.control.Button
        nearestpeakButton             matlab.ui.control.Button
        viewButton                    matlab.ui.control.Button
        maxvalueEditFieldLabel        matlab.ui.control.Label
        maxvalueEditField             matlab.ui.control.NumericEditField
        AnatLabel                     matlab.ui.control.Label
        numberofpeaksEditFieldLabel   matlab.ui.control.Label
        numberofpeaksEditField        matlab.ui.control.NumericEditField
        imageDropDownLabel_2          matlab.ui.control.Label
        imageDropDownLabel_3          matlab.ui.control.Label
        imageDropDownLabel_4          matlab.ui.control.Label
        imageDropDownLabel_5          matlab.ui.control.Label
        saveasxlsxButton              matlab.ui.control.Button
        imageDropDownLabel_6          matlab.ui.control.Label
        atlasLabel                    matlab.ui.control.Label
        atlasDropDown                 matlab.ui.control.DropDown
    end


    properties (Access = private)
        SPM % Description
        atlas % Description
    end

    properties (Access = public)
        imgDat % Description
    end

    methods (Access = private)


        function selectUnderlay(app)
            [filename, pathname] = uigetfile({'*.nii;*.hdr','Nifit-images'}, 'Select structural underlay','MultiSelect', 'off');
            if ischar(filename)
                app.structuralunderlayEditField.Value = fullfile(pathname, filename);
                app.initOrthviews();
            end
        end

        function initOrthviews(app)
            addpath('scripts');
            afxOrthviews('init', app.structuralunderlayEditField.Value);
            rmpath('scripts');
        end
    
        function initAtlas(app)
            addpath('scripts');
            atlasNii = fullfile('rois','atlas',strcat(app.atlasDropDown.Value,'.nii'));
            atlasLabels = fullfile('rois','atlas',strcat(app.atlasDropDown.Value,'.txt'));
            V = spm_vol(atlasNii);
            [Y,XYZ] = spm_read_vols(V);
            app.atlas.dat = Y;
            app.atlas.XYZ = XYZ;
            tmp = afxCsvRead(atlasLabels,' ');
            app.atlas.labels = {};
            app.atlas.labels([tmp{:,1}]) = tmp(:,2);
            rmpath('scripts');
        end
        
        function visualize(app)
            addpath('scripts');
            if ~isempty(app.imagesDropDown.Value)
                if ischar(app.SPM)
                    app.imgDat = afxOrthviews('spm', app.SPM, app.imagesDropDown.Value, app.hightthresholdEditField.Value, app.clusterextentEditField.Value, false);
                else
                    if app.maskButton.Value
                        afxOrthviews('reset');
                        app.imgDat = afxOrthviews('mask', app.imagesDropDown.Value);
                    elseif app.x1x1mmButton.Value
                        app.imgDat = afxOrthviews('nii_resample', app.imagesDropDown.Value, app.hightthresholdEditField.Value, app.clusterextentEditField.Value, 1, false);
                    elseif app.x5x5mmButton.Value
                        app.imgDat = afxOrthviews('nii_resample', app.imagesDropDown.Value, app.hightthresholdEditField.Value, app.clusterextentEditField.Value, .5, false);
                    elseif app.originalButton.Value
                        app.imgDat = afxOrthviews('nii', app.imagesDropDown.Value, app.hightthresholdEditField.Value, app.clusterextentEditField.Value, false);
                    end
                end
            end
            if ~isinf(app.maxvalueEditField.Value) && app.maxvalueEditField.Value ~= 0 && ~isempty(app.imgDat.Z) && ~app.maskButton.Value
                spm_orthviews('SetBlobsMax', 1, 1, app.maxvalueEditField.Value);
            end
            rmpath('scripts');
        end
        
        function getCoords(app)
            addpath('scripts');
            pos = round(afxOrthviews('pos'));
            app.XSpinner.Value = pos(1);
            app.YSpinner.Value = pos(2);
            app.ZSpinner.Value = pos(3);
            app.AnatLabel.Text = app.getLabel(pos);
            rmpath('scripts');
        end
        
        function setCoords(app)
            pos = [app.XSpinner.Value app.YSpinner.Value app.ZSpinner.Value];
            spm_orthviews('Reposition',pos);
            app.AnatLabel.Text = app.getLabel(pos');
        end
        
        function nearestPeak(app)
            if isstruct(app.imgDat)
                addpath('scripts');
                pos = round(afxOrthviews('pos'));
                rmpath('scripts');
                [~,~,M,~,~] = spm_max(app.imgDat.Z,app.imgDat.RCP(1:3,:));
                if ~isempty(M)
                    XYZ = app.imgDat.mat*[M; ones(1,size(M,2))];
                    D = sqrt(sum((XYZ(1:3,:)-pos).^2));
                    idx = find(D==min(D));
                    if ~isempty(idx)
                        spm_orthviews('Reposition',XYZ(1:3,idx(1)));
                    end
                    app.getCoords();
                end
            end
        end
        
        function label = getLabel(app,coord)
            d = sqrt(sum((app.atlas.XYZ-coord).^2));
            idxAtlas = find(d == min(d));
            num = round(app.atlas.dat(idxAtlas(1)));
            if num == 0
                label = 'unknown';
            else
                label = app.atlas.labels{num};
            end
        end
        
        function tbl = resultsTable(app)
            tbl = {'cluster-level','voxel-level','','';'k','val','x,y,z (mm)','anatomical label'};
            if isstruct(app.imgDat)
                [N,Z,M,A,~] = spm_max(app.imgDat.Z,app.imgDat.RCP(1:3,:));
                if ~isempty(N)
                    XYZ = app.imgDat.mat*[M; ones(1,size(M,2))];
                    line = 3;
                    % iterate over clusters
                    for iClust = 1:max(A)
                        % get associated voxels
                        idxClust = A == iClust;
                        % sort by value
                        [B,I] = sort(Z(idxClust),'descend');
                        % cluster extent
                        n = N(find(idxClust,1));
                        tbl{line,1} = n;
                        if app.numberofpeaksEditField.Value > 0
                            % iterate over peaks
                            for iPeak = 1:min(length(I),app.numberofpeaksEditField.Value)
                                % value
                                z = B(iPeak);
                                tbl{line,2} = z;
                                % coordinate
                                tmp = XYZ(1:3,idxClust);
                                xyz = tmp(:,I(iPeak));
                                xyzStr = mat2str(xyz');
                                tbl{line,3} = xyzStr(2:end-1);
                                label = app.getLabel(xyz);
                                tbl{line,4} = label;
                                line = line + 1;
                            end
                        else
                            % cluster labeling
                            tbl{line,2} = '-';
                            tbl{line,3} = '-';
                            % coordinate
                            xyz = XYZ(1:3,idxClust);
                            labels = {};
                            for iVox = 1:size(xyz,2)
                                labels{end+1} = app.getLabel(xyz(:,iVox));
                            end
                            [c,ia,~] = unique(sort(labels));
                            n = diff([ia;size(xyz,2)+1]);
                            n = n/sum(n)*100;
                            [n,i] = sort(n,'descend');
                            labels = c(i);
                            for iLabel = 1:length(n)
                                if iLabel > 1 
                                    tbl{line,4} = [tbl{line,4}, ', '];
                                end
                                tbl{line,4} = [tbl{line,4}, sprintf('%s (%.1f %%)',labels{iLabel},n(iLabel))];
                            end
                            line = line + 1;
                        end
                    end
                end
            end
        end
        
        function saveNii(app)
            [filename, pathname] = uiputfile('*.nii', 'Save as ...');
            if ischar(filename)
                destFile = fullfile(pathname,filename);
                spm_write_filtered(app.imgDat.Z,app.imgDat.RCP,app.imgDat.dim,app.imgDat.mat,'',destFile);
            end
         end

        function saveBitmap(app)
            [filename, pathname] = uiputfile('*.png', 'Save as ...');
            if ischar(filename)
                destFile = fullfile(pathname,filename);
                f = spm_figure('GetWin','Graphics');
                figure(f);
                saveas(f, destFile, 'png')
            end
         end
    end

    methods (Access = public)
         function selectImages(app, filename, filterindex, titles)
            if ~exist('titles','var')
                titles = {};
            end
            if ~iscell(filename)
                filename = {filename};
            end
            if filterindex == 1
                app.imagesDropDown.Items = {};
                app.imagesDropDown.ItemsData = {};
                app.SPM = false;
                d = '';
                for i = 1:length(filename)
                    [d,f,~] = fileparts(filename{i});
                    if ~isempty(titles)
                        app.imagesDropDown.Items{i} = titles{i};
                    else
                        app.imagesDropDown.Items{i} = f;
                    end
                    app.imagesDropDown.ItemsData{i} = filename{i};
                end
                app.Label.Text = d;
                app.originalButton.Enable = 'on';
                app.maskButton.Enable = 'on';
                app.x1x1mmButton.Enable = 'on';
                app.x5x5mmButton.Enable = 'on';
                app.visualize();
            elseif filterindex == 2
                app.imagesDropDown.Items = {};
                app.imagesDropDown.ItemsData = {};
                app.SPM = filename{1};
                SPM = load(app.SPM);
                for i = 1:length(SPM.SPM.xCon)
                    app.imagesDropDown.Items{i} = sprintf('%.2d: %s',i,SPM.SPM.xCon(i).name);
                    app.imagesDropDown.ItemsData{i} = i;
                end
                app.Label.Text = filename{1};
                app.hightthresholdEditField.Value = .001;
                app.clusterextentEditField.Value = .05;
                app.originalButton.Value = true;
                app.originalButton.Enable = 'off';
                app.maskButton.Enable = 'off';
                app.x1x1mmButton.Enable = 'off';
                app.x5x5mmButton.Enable = 'off';
                app.visualize();
            end
            % filterindex == 0 -> no selection
         end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initOrthviews();
            app.initAtlas();
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            app.visualize();
        end

        % Button pushed function: SelectImagesButton
        function SelectImagesButtonPushed(app, event)
            [filename, pathname, filterindex] = uigetfile({'*.nii;*.hdr','Nifit-images'; 'SPM.mat','SPM'}, 'Select images','MultiSelect', 'on');
            app.selectImages(fullfile(pathname,filename), filterindex);
        end

        % Button pushed function: SelectUnderlayButton
        function SelectUnderlayButtonPushed(app, event)
            app.selectUnderlay();
            if ~isempty(app.imagesDropDown.Value)
                app.visualize();
            end
        end

        % Value changed function: XSpinner
        function XSpinnerValueChanged(app, event)
            value = app.XSpinner.Value;
            app.setCoords();
        end

        % Value changed function: YSpinner
        function YSpinnerValueChanged(app, event)
            value = app.YSpinner.Value;
            app.setCoords();
        end

        % Value changed function: ZSpinner
        function ZSpinnerValueChanged(app, event)
            value = app.ZSpinner.Value;
            app.setCoords();
        end

        % Value changed function: clusterextentEditField
        function clusterextentEditFieldValueChanged(app, event)
            value = app.clusterextentEditField.Value;
            app.visualize();
        end

        % Value changed function: crosshairCheckBox
        function crosshairCheckBoxValueChanged(app, event)
            value = app.crosshairCheckBox.Value;
            if value
                spm_orthviews('Xhairs','on');
            else
                spm_orthviews('Xhairs','off');
            end
        end

        % Value changed function: hightthresholdEditField
        function hightthresholdEditFieldValueChanged(app, event)
            value = app.hightthresholdEditField.Value;
            app.visualize();
        end

        % Value changed function: imagesDropDown
        function imagesDropDownValueChanged(app, event)
            value = app.imagesDropDown.Value;
            app.visualize();
        end

        % Value changed function: maxvalueEditField
        function maxvalueEditFieldValueChanged(app, event)
            value = app.maxvalueEditField.Value;
            app.visualize();
        end

        % Button pushed function: nearestpeakButton
        function nearestpeakButtonPushed(app, event)
            app.nearestPeak();
        end

        % Callback function
        function renderasmaskCheckBoxValueChanged(app, event)
            value = app.renderasmaskCheckBox.Value;
            app.visualize();
        end

        % Callback function
        function resampleto1x1x1mmCheckBoxValueChanged(app, event)
            value = app.resampleto1x1x1mmCheckBox.Value;
            app.visualize();
        end

        % Button pushed function: saveasbitmapButton
        function saveasbitmapButtonPushed(app, event)
            app.saveBitmap();
        end

        % Button pushed function: saveasniftiButton
        function saveasniftiButtonPushed(app, event)
            app.saveNii();
        end

        % Button pushed function: saveasxlsxButton
        function saveasxlsxButtonPushed(app, event)
            [filename, pathname] = uiputfile('*.xlsx', 'Save as ...');
            if ischar(filename)
                tbl = app.resultsTable();
                destFile = fullfile(pathname,filename);
                xlswrite(destFile,tbl);
            end
        end

        % Button pushed function: updateButton
        function updateButtonPushed(app, event)
            app.getCoords();
        end

        % Button pushed function: viewButton
        function viewButtonPushed(app, event)
            tbl = app.resultsTable();
            disp(tbl);
            assignin('base','resultsTable',tbl);
        end

        % Value changed function: atlasDropDown
        function atlasDropDownValueChanged(app, event)
            app.initAtlas();
            app.getCoords();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.afxViewerUIFigure = uifigure;
            app.afxViewerUIFigure.Name = 'afxViewer';
            app.imagesDropDownLabel = uilabel(app.afxViewerUIFigure);
            app.imagesDropDownLabel.Text = 'images:';
            app.imagesDropDown = uidropdown(app.afxViewerUIFigure);
            app.imagesDropDown.Items = {};
            app.imagesDropDown.ValueChangedFcn = createCallbackFcn(app, @imagesDropDownValueChanged, true);
            app.imagesDropDown.Value = {};
            app.hightthresholdEditFieldLabel = uilabel(app.afxViewerUIFigure);
            app.hightthresholdEditFieldLabel.Text = 'hight threshold';
            app.hightthresholdEditField = uieditfield(app.afxViewerUIFigure, 'numeric');
            app.hightthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @hightthresholdEditFieldValueChanged, true);
            app.clusterextentEditFieldLabel = uilabel(app.afxViewerUIFigure);
            app.clusterextentEditFieldLabel.Text = 'cluster extent';
            app.clusterextentEditField = uieditfield(app.afxViewerUIFigure, 'numeric');
            app.clusterextentEditField.Limits = [0 Inf];
            app.clusterextentEditField.ValueChangedFcn = createCallbackFcn(app, @clusterextentEditFieldValueChanged, true);
            app.structuralunderlayLabel = uilabel(app.afxViewerUIFigure);
            app.structuralunderlayLabel.Text = 'structural underlay:';
            app.structuralunderlayEditField = uieditfield(app.afxViewerUIFigure, 'text');
            app.structuralunderlayEditField.Editable = 'off';
            app.structuralunderlayEditField.Value = 'templates\MNI152_T1_0.5mm_masked.nii';
            app.SelectUnderlayButton = uibutton(app.afxViewerUIFigure, 'push');
            app.SelectUnderlayButton.ButtonPushedFcn = createCallbackFcn(app, @SelectUnderlayButtonPushed, true);
            app.SelectUnderlayButton.Text = 'select';
            app.XSpinnerLabel = uilabel(app.afxViewerUIFigure);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.afxViewerUIFigure);
            app.XSpinner.Limits = [-200 200];
            app.XSpinner.ValueDisplayFormat = '%.0f';
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.YSpinnerLabel = uilabel(app.afxViewerUIFigure);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.afxViewerUIFigure);
            app.YSpinner.Limits = [-200 200];
            app.YSpinner.ValueDisplayFormat = '%.0f';
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @YSpinnerValueChanged, true);
            app.ZSpinnerLabel = uilabel(app.afxViewerUIFigure);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.afxViewerUIFigure);
            app.ZSpinner.Limits = [-200 200];
            app.ZSpinner.ValueDisplayFormat = '%.0f';
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSpinnerValueChanged, true);
            app.positionMNImmLabel = uilabel(app.afxViewerUIFigure);
            app.positionMNImmLabel.Text = 'position (MNI/mm):';
            app.SelectImagesButton = uibutton(app.afxViewerUIFigure, 'push');
            app.SelectImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImagesButtonPushed, true);
            app.SelectImagesButton.Text = 'select';
            app.updateButton = uibutton(app.afxViewerUIFigure, 'push');
            app.updateButton.ButtonPushedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.updateButton.Text = 'update';
            app.Label = uilabel(app.afxViewerUIFigure);
            app.Label.Text = '';
            app.crosshairCheckBox = uicheckbox(app.afxViewerUIFigure);
            app.crosshairCheckBox.ValueChangedFcn = createCallbackFcn(app, @crosshairCheckBoxValueChanged, true);
            app.crosshairCheckBox.Text = 'crosshair';
            app.crosshairCheckBox.Value = true;
            app.ButtonGroup = uibuttongroup(app.afxViewerUIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.originalButton = uiradiobutton(app.ButtonGroup);
            app.originalButton.Text = 'original';
            app.originalButton.Value = true;
            app.maskButton = uiradiobutton(app.ButtonGroup);
            app.maskButton.Text = 'mask';
            app.x1x1mmButton = uiradiobutton(app.ButtonGroup);
            app.x1x1mmButton.Text = '1x1x1 mm';
            app.x5x5mmButton = uiradiobutton(app.ButtonGroup);
            app.x5x5mmButton.Text = '.5x.5x.5 mm';
            app.saveasniftiButton = uibutton(app.afxViewerUIFigure, 'push');
            app.saveasniftiButton.ButtonPushedFcn = createCallbackFcn(app, @saveasniftiButtonPushed, true);
            app.saveasniftiButton.Text = 'save as nifti';
            app.saveasbitmapButton = uibutton(app.afxViewerUIFigure, 'push');
            app.saveasbitmapButton.ButtonPushedFcn = createCallbackFcn(app, @saveasbitmapButtonPushed, true);
            app.saveasbitmapButton.Text = 'save as bitmap';
            app.nearestpeakButton = uibutton(app.afxViewerUIFigure, 'push');
            app.nearestpeakButton.ButtonPushedFcn = createCallbackFcn(app, @nearestpeakButtonPushed, true);
            app.nearestpeakButton.Text = 'nearest peak';
            app.viewButton = uibutton(app.afxViewerUIFigure, 'push');
            app.viewButton.ButtonPushedFcn = createCallbackFcn(app, @viewButtonPushed, true);
            app.viewButton.Text = 'view';
            app.maxvalueEditFieldLabel = uilabel(app.afxViewerUIFigure);
            app.maxvalueEditFieldLabel.Text = 'max value';
            app.maxvalueEditField = uieditfield(app.afxViewerUIFigure, 'numeric');
            app.maxvalueEditField.Limits = [0 Inf];
            app.maxvalueEditField.ValueChangedFcn = createCallbackFcn(app, @maxvalueEditFieldValueChanged, true);
            app.AnatLabel = uilabel(app.afxViewerUIFigure);
            app.AnatLabel.Text = '';
            app.numberofpeaksEditFieldLabel = uilabel(app.afxViewerUIFigure);
            app.numberofpeaksEditFieldLabel.Text = 'number of peaks';
            app.numberofpeaksEditField = uieditfield(app.afxViewerUIFigure, 'numeric');
            app.numberofpeaksEditField.Limits = [0 Inf];
            app.numberofpeaksEditField.Value = 3;
            app.imageDropDownLabel_2 = uilabel(app.afxViewerUIFigure);
            app.imageDropDownLabel_2.Text = 'mode:';
            app.imageDropDownLabel_3 = uilabel(app.afxViewerUIFigure);
            app.imageDropDownLabel_3.Text = 'thresholds:';
            app.imageDropDownLabel_4 = uilabel(app.afxViewerUIFigure);
            app.imageDropDownLabel_4.Text = 'label:';
            app.imageDropDownLabel_5 = uilabel(app.afxViewerUIFigure);
            app.imageDropDownLabel_5.Text = 'results table:';
            app.saveasxlsxButton = uibutton(app.afxViewerUIFigure, 'push');
            app.saveasxlsxButton.ButtonPushedFcn = createCallbackFcn(app, @saveasxlsxButtonPushed, true);
            app.saveasxlsxButton.Text = 'save as xlsx';
            app.imageDropDownLabel_6 = uilabel(app.afxViewerUIFigure);
            app.imageDropDownLabel_6.Text = 'save:';
            app.atlasLabel = uilabel(app.afxViewerUIFigure);
            app.atlasLabel.Text = 'atlas:';
            app.atlasDropDown = uidropdown(app.afxViewerUIFigure);
            app.atlasDropDown.Items = {'Loni propability', 'HarvardOxford (Cortical)', 'HarvardOxford (Subcortical)', 'AAL2', 'Brodmann', 'AICHA', 'Brainetome', 'Thalamus', 'Subcortical', 'Natbrainlab (wm)'};
            app.atlasDropDown.ItemsData = {'lpba40', 'HarvardOxford-Cortical', 'HarvardOxford-Subcortical', 'aal2', 'brodmann', 'AICHA', 'BNA', 'thalamus', 'subcortical', 'natbrainlab'};
            app.atlasDropDown.ValueChangedFcn = createCallbackFcn(app, @atlasDropDownValueChanged, true);
            app.atlasDropDown.Value = 'lpba40';
        end
    end

    methods (Access = public)

        % Construct app
        function app = afxViewer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.afxViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.afxViewerUIFigure)
        end
    end
end